classdef Res2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResistorCalcUIFigure         matlab.ui.Figure
        Text                         matlab.ui.control.TextArea
        text                         matlab.ui.control.TextArea
        Image2                       matlab.ui.control.Image
        BantButtonGroup              matlab.ui.container.ButtonGroup
        bant5                        matlab.ui.control.RadioButton
        bant4                        matlab.ui.control.RadioButton
        RenkPanel                    matlab.ui.container.Panel
        Renk5                        matlab.ui.control.DropDown
        RenkToleransDropDown_2Label  matlab.ui.control.Label
        Renk3                        matlab.ui.control.DropDown
        RenkCarpanDropDownLabel      matlab.ui.control.Label
        Renk4                        matlab.ui.control.DropDown
        RenkToleransDropDownLabel    matlab.ui.control.Label
        Renk2                        matlab.ui.control.DropDown
        Renk2BantDropDownLabel       matlab.ui.control.Label
        Renk1                        matlab.ui.control.DropDown
        Renk1BantDropDownLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        resVal='00';
        resMul=1;
        tol='±1%';
        tolerans={'±1%' '±2%' '±0.5%' '±0.25%' '±0.1%' '±0.05%' '±5%' '±10%'};
        resImg;
        res41;res42;res43;res44;res55;
        
        Black =[0 0 0];
        Brown =[139 69 19];
        Red =[255 0 0];
        Orange =[255 153 0];
        Yellow =[255 255 0];
        Green =[0 255 0];
        Blue =[0 0 255];
        Violet =[255 0 255];
        Grey =[204 204 204];
        White =[255 255 255];
        Gold =[225 215 0];
        Silver =[217 217 217];
             
        fcolor=[20 20 20]
        scolor=[30 30 30]
        thcolor=[40 40 40];
        frcolor=[50 50 50];
        fvcolor=[60 60 60];
        
        colors;
    end
    methods (Access = private)
        
        function rgbImage = colorChanger(app,imgSrc,colorNum, rgbInMat, rgbOutMat)
            
            %First arg image adress
            %Second arg the number of colors which will change
            %Third arg the RGB values of colors which will change in 3 by number of colors Matrix
            %Forth arg the RGB values of new colors in 3 by number of colors Matrix
            
            rgbImage = imread(imgSrc);
            %RGB renklerini al
            redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            blueChannel = rgbImage(:, :, 3);
            %giris err
            if nargin~=5
                error('Not enough input arguments.');
            elseif size(rgbInMat, 1)~=colorNum && length(rgbInMat)~=3 && length(rgbOutMat)~=3
                error('The numberof column must be 3 and the number of row of input colors matrix must be the same as the number of colors');
            elseif ~ischar(imgSrc)
                error('The image adress must be string ');
            else
                if size(rgbInMat)==size(rgbOutMat)
                    for  i=1:colorNum
                        %degisecek renk
                        renk= redChannel == rgbInMat(i,1) & greenChannel  == rgbInMat(i,2) & blueChannel  == rgbInMat(i,3);
                        %yeni renk
                        redChannel(renk) = rgbOutMat(i,1);greenChannel(renk) = rgbOutMat(i,2);blueChannel(renk) = rgbOutMat(i,3);
                    end
                elseif size(rgbOutMat, 1)==1
                    for  i=1:colorNum
                        renk= redChannel == rgbInMat(i,1) & greenChannel  == rgbInMat(i,2) & blueChannel  == rgbInMat(i,3);
                        redChannel(renk) = rgbOutMat(1);greenChannel(renk) = rgbOutMat(2);blueChannel(renk) = rgbOutMat(3);
                    end
                else
                    error('The number of row of output colors matrix must be 1 or same as the number of colors');
                end
                %fotoyu olustur
                rgbImage = cat(3, redChannel, greenChannel, blueChannel);
                app.resImg=rgbImage;
            end
            
        end
        
        function carpan(app)
            lastval=str2double(app.resVal)*app.resMul;
            if lastval>=1e3 && lastval<1e6
                app.text.Value=[num2str(lastval/10^3) 'K' ' Ohm ' app.tol];
            elseif lastval>=1e6 && lastval<1e9
                app.text.Value=[num2str(lastval/10^6) 'M' ' Ohm ' app.tol];
            elseif lastval>=1e9
                app.text.Value=[num2str(lastval/10^9) 'G' ' Ohm ' app.tol];
            else
                app.text.Value=[num2str(lastval) ' Ohm ' app.tol];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colorChanger(app,'resis4.png', 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [app.Black;app.Black;app.Black;app.Brown]);
            app.res41=app.Black;app.res42=app.Black;app.res43=app.Black;app.res44=app.Brown;
            app.Image2.ImageSource=app.resImg;
            app.colors={app.Black,app.Brown,app.Red,app.Orange,app.Yellow,app.Green,app.Blue,app.Violet,app.Grey,app.White,app.Gold,app.Silver};
            pause(2);
            app.Image.Visible=1;app.RenkPanel.Visible=1;app.BantButtonGroup.Visible=1;app.Image2.Visible=1;app.text.Visible=1;app.Text.Visible=0;
        end

        % Value changed function: Renk4
        function Renk4ValueChanged(app, event)
            value = str2double(app.Renk4.Value);
            color={app.Brown,app.Red,app.Green,app.Blue,app.Violet,app.Grey,app.Gold,app.Silver};
            if app.bant4.Value
                app.tol=app.tolerans{value};
                app.res44=cell2mat(color(value));
                Imgsrc='resis4.png';
                colorChanger(app,Imgsrc, 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [app.res41;app.res42;app.res43;app.res44]);
                app.Image2.ImageSource=app.resImg;
            else
                Imgsrc='resis5.png';
                app.res44=cell2mat(app.colors(value));
                if value==11||value==12
                    app.resMul=10^(10-value);
                else
                    app.resMul=10^(value-1);
                end
                colorChanger(app,Imgsrc, 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.res41;app.res42;app.res43;app.res44;app.res55]);
                app.Image2.ImageSource=app.resImg;
            end
            app.carpan;
        end

        % Value changed function: Renk5
        function Renk5ValueChanged(app, event)
            value = str2double(app.Renk5.Value);
            Imgsrc='resis5.png';
            app.tol=app.tolerans{value};         
            color={app.Brown,app.Red,app.Green,app.Blue,app.Violet,app.Grey,app.Gold,app.Silver};
            app.res55=cell2mat(color(value));
            colorChanger(app,Imgsrc, 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.res41;app.res42;app.res43;app.res44;app.res55]);
            app.Image2.ImageSource=app.resImg;
            app.carpan;
        end

        % Value changed function: Renk1
        function Renk1ValueChanged(app, event)
            value=str2double(app.Renk1.Value);
            app.resVal(1)=num2str(value-1);
            app.res41=cell2mat(app.colors(value));
            if app.bant4.Value
                Imgsrc='resis4.png';
                colorChanger(app,Imgsrc, 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [cell2mat(app.colors(value));app.res42;app.res43;app.res44]);
            else
                Imgsrc='resis5.png';
                colorChanger(app,Imgsrc, 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.res41;app.res42;app.res43;app.res44;app.res55]);
            end
            app.Image2.ImageSource=app.resImg;
            app.carpan;
        end

        % Value changed function: Renk2
        function Renk2ValueChanged(app, event)
            value = str2double(app.Renk2.Value);
            app.resVal(2)=num2str(value-1);
            app.res42=cell2mat(app.colors(value));
            if app.bant4.Value
                Imgsrc='resis4.png';
                colorChanger(app,Imgsrc, 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [app.res41;cell2mat(app.colors(value));app.res43;app.res44]);
            else
                Imgsrc='resis5.png';
                colorChanger(app,Imgsrc, 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.res41;cell2mat(app.colors(value));app.res43;app.res44;app.res55]);
            end
            app.Image2.ImageSource=app.resImg;
            app.carpan;
        end

        % Value changed function: Renk3
        function Renk3ValueChanged(app, event)
            value = str2double(app.Renk3.Value);
            app.res43=cell2mat(app.colors(value));
            if app.bant4.Value
                Imgsrc='resis4.png';
                if value==11||value==12
                    app.resMul=10^(10-value);
                else
                    app.resMul=10^(value-1);
                end
                colorChanger(app,Imgsrc, 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [app.res41;app.res42;cell2mat(app.colors(value));app.res44]);
                app.Image2.ImageSource=app.resImg;                
            else
                Imgsrc='resis5.png';
                app.resVal(3)=num2str(value-1);
                colorChanger(app,Imgsrc, 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.res41;app.res42;app.res43;app.res44;app.res55]);
                app.Image2.ImageSource=app.resImg;
            end
            app.carpan;  
        end

        % Selection changed function: BantButtonGroup
        function BantButtonGroupSelectionChanged(app, event)
            selectedButton = app.BantButtonGroup.SelectedObject;
            app.Renk1.Value='1';
            app.Renk2.Value='1';
            app.Renk3.Value='1';
            app.Renk4.Value='1';
            app.text.Value= '';
            
            if selectedButton==app.bant5
                %%RENK TABLOSU
                app.RenkCarpanDropDownLabel.Text='3.Renk(3.Bant)'; %3.renk txt
                app.RenkToleransDropDownLabel.Text='4.Renk (Carpan)'; %4.renk txt
                app.Renk3.Items={'Siyah' 'Kahverengi' 'Kırmızı' 'Turuncu' 'Sarı' 'Yeşil' 'Mavi' 'Mor' 'Gri' 'Beyaz'};
                for i=1:10
                    app.Renk3.ItemsData(i)={num2str(i)};
                end
                app.Renk4.Items={'Siyah' 'Kahverengi' 'Kırmızı' 'Turuncu' 'Sarı' 'Yeşil' 'Mavi' 'Mor' 'Gri' 'Beyaz' 'Altın' 'Gümüş'};
                for i=1:12
                    app.Renk4.ItemsData(i)={num2str(i)};
                end
                app.RenkToleransDropDown_2Label.Visible=1;
                app.Renk5.Visible=1;           
                %%FOTOGRAF TABLOSU
                colorChanger(app,'resis5.png', 5, [app.fcolor;app.scolor;app.thcolor;app.frcolor;app.fvcolor], [app.Black;app.Black;app.Black;app.Black;app.Brown]);
                app.Image2.ImageSource=app.resImg;
                app.res41=app.Black;app.res42=app.Black;app.res43=app.Black;app.res44=app.Black;app.res55=app.Brown;
                
                app.resVal='000';
            else
                %%RENK TABLOSU
                app.RenkCarpanDropDownLabel.Text='3.Renk (Carpan)'; %3.renk lbl txt
                app.RenkToleransDropDownLabel.Text='4.Renk (Tolerans)'; %4.renk lbl txt
                app.Renk3.Items={'Siyah' 'Kahverengi' 'Kırmızı' 'Turuncu' 'Sarı' 'Yeşil' 'Mavi' 'Mor' 'Gri' 'Beyaz' 'Altın' 'Gümüş'};%3.renk menu
                for i=1:12
                    app.Renk3.ItemsData(i)={num2str(i)}; %3.renk menu index
                end
                app.Renk4.Items={'Kahverengi' 'Kırmızı' 'Yeşil' 'Mavi' 'Mor' 'Gri' 'Altın' 'Gümüş'};%4.renk menu
                for i=1:8
                    app.Renk4.ItemsData(i)={num2str(i)}; %4.renk menu index
                end
                app.RenkToleransDropDown_2Label.Visible=0;
                app.Renk5.Visible=0;
                colorChanger(app,'resis4.png', 4, [app.fcolor;app.scolor;app.thcolor;app.frcolor], [app.Black;app.Black;app.Black;app.Brown]);
                app.Image2.ImageSource=app.resImg;
                app.res41=app.Black;app.res42=app.Black;app.res43=app.Black;app.res43=app.Brown;
                
                app.resVal='00';
            end
        end

        % Size changed function: RenkPanel
        function RenkPanelSizeChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResistorCalcUIFigure = uifigure('Visible', 'off');
            app.ResistorCalcUIFigure.Name = 'Resistor Calc';
            app.Image = uiimage(app.ResistorCalcUIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'Res.jpg';
            app.RenkPanel = uipanel(app.ResistorCalcUIFigure);
            app.RenkPanel.Title = 'Renk';
            app.RenkPanel.Visible = 'off';
            app.RenkPanel.SizeChangedFcn = createCallbackFcn(app, @RenkPanelSizeChanged, true);
            app.Renk1BantDropDownLabel = uilabel(app.RenkPanel);
            app.Renk1BantDropDownLabel.Text = '1.Renk (1.Bant)';
            app.Renk1 = uidropdown(app.RenkPanel);
            app.Renk1.Items = {'', 'Siyah', 'Kahverengi', 'Kırmızı', 'Turuncu	', 'Sarı', 'Yeşil', 'Mavi', 'Mor', 'Gri', 'Beyaz'};
            app.Renk1.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Renk1.ValueChangedFcn = createCallbackFcn(app, @Renk1ValueChanged, true);
            app.Renk1.Value = '1';
            app.Renk2BantDropDownLabel = uilabel(app.RenkPanel);
            app.Renk2BantDropDownLabel.Text = '2.Renk (2.Bant)';
            app.Renk2 = uidropdown(app.RenkPanel);
            app.Renk2.Items = {'Siyah', 'Kahverengi', 'Kırmızı', 'Turuncu	', 'Sarı', 'Yeşil', 'Mavi', 'Mor', 'Gri', 'Beyaz'};
            app.Renk2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Renk2.ValueChangedFcn = createCallbackFcn(app, @Renk2ValueChanged, true);
            app.Renk2.Value = '1';
            app.RenkToleransDropDownLabel = uilabel(app.RenkPanel);
            app.RenkToleransDropDownLabel.Text = '4.Renk (Tolerans)';
            app.Renk4 = uidropdown(app.RenkPanel);
            app.Renk4.Items = {'Kahverengi', 'Kırmızı	', 'Yeşil', 'Mavi', 'Mor', 'Gri', 'Altın', 'Gümüş'};
            app.Renk4.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Renk4.ValueChangedFcn = createCallbackFcn(app, @Renk4ValueChanged, true);
            app.Renk4.Value = '1';
            app.RenkCarpanDropDownLabel = uilabel(app.RenkPanel);
            app.RenkCarpanDropDownLabel.Text = '3.Renk (Carpan)';
            app.Renk3 = uidropdown(app.RenkPanel);
            app.Renk3.Items = {'Siyah', 'Kahverengi', 'Kırmızı', 'Turuncu	', 'Sarı', 'Yeşil', 'Mavi', 'Mor', 'Gri', 'Beyaz', 'Altın', 'Gümüş'};
            app.Renk3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.Renk3.ValueChangedFcn = createCallbackFcn(app, @Renk3ValueChanged, true);
            app.Renk3.Value = '1';
            app.RenkToleransDropDown_2Label = uilabel(app.RenkPanel);
            app.RenkToleransDropDown_2Label.Visible = 'off';
            app.RenkToleransDropDown_2Label.Text = '5.Renk (Tolerans)';
            app.Renk5 = uidropdown(app.RenkPanel);
            app.Renk5.Items = {'Kahverengi', 'Kırmızı	', 'Yeşil', 'Mavi', 'Mor', 'Gri', 'Altın', 'Gümüş'};
            app.Renk5.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Renk5.ValueChangedFcn = createCallbackFcn(app, @Renk5ValueChanged, true);
            app.Renk5.Visible = 'off';
            app.Renk5.Value = '1';
            app.BantButtonGroup = uibuttongroup(app.ResistorCalcUIFigure);
            app.BantButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BantButtonGroupSelectionChanged, true);
            app.BantButtonGroup.Title = 'Bant';
            app.BantButtonGroup.Visible = 'off';
            app.bant4 = uiradiobutton(app.BantButtonGroup);
            app.bant4.Text = '4 Bant';
            app.bant4.Value = true;
            app.bant5 = uiradiobutton(app.BantButtonGroup);
            app.bant5.Text = '5 Bant';
            app.Image2 = uiimage(app.ResistorCalcUIFigure);
            app.Image2.Visible = 'off';
            app.text = uitextarea(app.ResistorCalcUIFigure);
            app.text.Editable = 'off';
            app.text.Visible = 'off';
            app.Text = uitextarea(app.ResistorCalcUIFigure);
            app.Text.Editable = 'off';
            app.Text.Value = {'Hos Geldiniz'};
            % Show the figure after all components are created
            app.ResistorCalcUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Res2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResistorCalcUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResistorCalcUIFigure)
        end
    end
end